classdef Stand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        StepResampling       matlab.ui.control.NumericEditField
        StartresampleButton  matlab.ui.control.Button
        DeltasPlot           matlab.ui.control.UIAxes
        Label_4              matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        Label_5              matlab.ui.control.Label
        EditField_2          matlab.ui.control.NumericEditField
        LoadtickdataButton   matlab.ui.control.Button
        LampLabel            matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
    end


    properties (Access = private)
        TickData = table; % Description
        ResData = table;
    end

    methods (Access = private)
    
        function LoadData(app)
            filename = '/Users/admin/PycharmProjects/TestPandas/tick.csv';
            delimiter = ',';
            startRow = 2;
            formatSpec = '%*s%{yyyy-MM-dd HH:mm:ss}D%f%f%f%[^\n\r]';
            fileID = fopen(filename,'r');
            dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'EmptyValue' ,NaN,'HeaderLines' ,startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            fclose(fileID);
            DateTime = dataArray{:, 1};
            Price = dataArray{:, 2};
            VolBuy = dataArray{:, 3};
            VolSell = dataArray{:, 4};
            Delta = VolBuy - VolSell;
            CumDelta = cumsum(Delta);
            AbsCumDelta = cumsum(abs(Delta));
            app.TickData = table(DateTime, Price, VolBuy, VolSell, Delta, CumDelta, AbsCumDelta);
            clearvars filename delimiter startRow formatSpec fileID dataArray ans;
        end
        
        function Mres(app, event)
            deltas = app.StepResampling.Value;
    %        x = app.TickData.AbsCumDelta(1:deltas:end);
    %        y = app.TickData.Price(1:deltas:end);
            
            %Цикл по OHLC
            op = zeros(1, int64(max(app.TickData.AbsCumDelta)/deltas));
            hi = zeros(1, int64(max(app.TickData.AbsCumDelta)/deltas));
            lo = zeros(1, int64(max(app.TickData.AbsCumDelta)/deltas));
            cl = zeros(1, int64(max(app.TickData.AbsCumDelta)/deltas));
            da = repmat(datetime(0, 0, 0, 0, 0, 0), 1, int64(max(app.TickData.AbsCumDelta)/deltas));
            
            for i = 1:int64(max(app.TickData.AbsCumDelta)/deltas)
                ss = (i-1)*deltas;      % Левая граница выбора
                sd = deltas*i;           % Правая граница выбора
                t = app.TickData.Price(app.TickData.AbsCumDelta>=ss & app.TickData.AbsCumDelta<sd);
                da(i) = max(app.TickData.DateTime(app.TickData.AbsCumDelta>=ss & app.TickData.AbsCumDelta<sd));
                hi(i) = max(t);
                lo(i) = min(t);
                op(i) = t(1);
                cl(i) = t(end);
            end
                d = datenum(da');
                h = hi';
                l = lo';
                o = op';
                c = cl';
        %        x1 = 1:int64(max(app.TickData.AbsCumDelta)/deltas);
            app.Lamp.Enable = 'off';
       end
        
    end


    methods (Access = private)

        % Button pushed function: StartresampleButton
        function StartresampleButtonPushed(app, event)
            app.Mres();
       %    plot(app.DeltasPlot, c);
        end

        % Button pushed function: LoadtickdataButton
        function LoadtickdataButtonPushed(app, event)
            app.LoadData();
            app.Lamp.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Стенд ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Метод ресэмплинга';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Дельты', 'Тики'};
            app.DropDown.Value = 'Дельты';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Шаг ресэмплинга';
            app.StepResampling = uieditfield(app.UIFigure, 'numeric');
            app.StepResampling.Limits = [100 2000];
            app.StepResampling.ValueDisplayFormat = '%.0f';
            app.StepResampling.Value = 500;
            app.StartresampleButton = uibutton(app.UIFigure, 'push');
            app.StartresampleButton.ButtonPushedFcn = createCallbackFcn(app, @StartresampleButtonPushed, true);
            app.StartresampleButton.Text = 'Start resample';
            app.DeltasPlot = uiaxes(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.MinorTicks = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Ширина окна, (час)';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LoadtickdataButton = uibutton(app.UIFigure, 'push');
            app.LoadtickdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtickdataButtonPushed, true);
            app.LoadtickdataButton.Text = 'Load tick data';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Stand()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end